classdef adjust_multicolor_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        RedSliderLabel      matlab.ui.control.Label
        RedSlider           matlab.ui.control.Slider
        GreenSliderLabel    matlab.ui.control.Label
        GreenSlider         matlab.ui.control.Slider
        BlueSliderLabel     matlab.ui.control.Label
        BlueSlider          matlab.ui.control.Slider
        LoadButton          matlab.ui.control.Button
        SelectHeadButton    matlab.ui.control.Button
        FlipLabel           matlab.ui.control.Label
        ResetSlidersButton  matlab.ui.control.Button
        SaveButton          matlab.ui.control.Button
        GammaGreenSlider    matlab.ui.control.Slider
        GammaBlueSlider     matlab.ui.control.Slider
        GammaRedSlider      matlab.ui.control.Slider
        Knob                matlab.ui.control.Knob
        XButton             matlab.ui.control.StateButton
        YButton             matlab.ui.control.StateButton
        TitleLabel          matlab.ui.control.Label
        ScaleLabel          matlab.ui.control.Label
        GammaLabel          matlab.ui.control.Label
        NoteXYflipsactlikephysicalflipsTheywillfliptheZaxisaswellLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        path;
        full_file;
        image; % the image we are currently working with
        image_tagRFP; % the image we are currently working with
        image_gcamp; % the image we are currently working with
        mask;
        original_image;
        flipZ;
        
        rgb_indicies = [5, 3, 1];
    end
    
    methods (Access = private)
        function max_val = max_of_channel(~, image_in, num_from_max)
            if nargin < 3
                num_from_max = 100;
            end
            
            image_size = size(image_in);
            
            switch length(size(image_in))
                case 3
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2), image_size(3)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 2]);
                case 4
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2)*image_size(3), image_size(4)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 4, 2]);
            end
        end
        
        function construct_image(app)
            %% mask image
            masked_image = app.original_image .* app.mask;

            %% normalize image
            max_val = app.max_of_channel(masked_image);
            
%             normalized_masked_image = masked_image ./ max(max(masked_image, [], 1), [], 2);
            normalized_masked_image = masked_image ./ max_val;
            normalized_masked_image(normalized_masked_image > 1) = 1;
            
            %% gamma image
            gamma = [app.GammaRedSlider.Value, app.GammaGreenSlider.Value, app.GammaBlueSlider.Value];
            gamma_image = imadjust(normalized_masked_image,[],[],gamma);
            
            %% weight image
            weights = app.get_weights();
            
            max_weights = max([max(weights) 0.05]);
            
            weighted_image = gamma_image .* weights ./ max_weights;
            
            %% flip_image
            if app.XButton.Value
                weighted_image = flip(weighted_image, 2);
                app.flipZ = ~app.flipZ;
            end
            
            if app.YButton.Value
                weighted_image = flip(weighted_image, 1);
                app.flipZ = ~app.flipZ;
            end
            
            %% rotate image
            app.image = imrotate(weighted_image, -app.Knob.Value, 'bilinear');
        end
        
        function plot_zstack(app)
            app.construct_image();
            
            imagesc(app.UIAxes, app.image);
        end
        
        function load_image(app)
            [file, path_in] = uigetfile('/home/mcreamer/Documents/data_sets/neuropal/creamer/*.aml');
            
            app.path = fullfile(path_in, file);
            app.full_file = load(app.path, '-mat');
            
            [date_folder_path, enclosing_folder] = fileparts(path_in(1:end-1));
            [~, date_folder] = fileparts(date_folder_path);
            file_name = fullfile(date_folder, enclosing_folder);
            app.TitleLabel.Text = file_name;
            
            % grab color data
            selected_channels = app.full_file.original_data(:, :, :, app.rgb_indicies);
            app.original_image = squeeze(max(selected_channels, [], 3));
            app.mask = app.full_file.mask;
            
            app.reset_sliders();
        end
        
        function weights = get_weights(app)
            weights = zeros(1, 1, 3);
            weights(1) = app.RedSlider.Value;
            weights(2) = app.GreenSlider.Value;
            weights(3) = app.BlueSlider.Value;
        end
        
        function save_image(app)
            % i think the easiest way to save this is to have the original
            % image and the modified image in the same structure. If we
            % just keep the modified, then you can't load the file again
            % because you'll apply gamma twice
            % if you just keep the parameters and the original file then
            % you have to reconstruct it in the neuroPAL software which
            % will duplicate code (since I don't know how to call all these
            % functions from neuroPAL)
            
            % gamma image
            gamma = ones(5, 1)/2;
            gamma(app.rgb_indicies) = [app.GammaRedSlider.Value, app.GammaGreenSlider.Value, app.GammaBlueSlider.Value];
            
            %% mask image
            masked_image = app.full_file.original_data .* app.mask;
            
            %% normalize image
            max_val = app.max_of_channel(masked_image);
            
%             normalized_masked_image = masked_image ./ max(max(masked_image, [], 1), [], 2);
            normalized_masked_image = masked_image ./ max_val;
            normalized_masked_image(normalized_masked_image > 1) = 1;
            
            gamma_image = zeros(size(normalized_masked_image));
            %% gamma image
            for ss = 1:size(normalized_masked_image, 3)
                for ff = 1:size(normalized_masked_image, 4)
                    gamma_image(:, :, ss, ff) = imadjust(normalized_masked_image(:, :, ss, ff), [], [], gamma(ff));
                end
            end
            
            %% weight image
            weights = app.get_weights();
            max_weights = max([max(weights) 0.05]);
            norm_weights = weights ./ max_weights;
            full_weights = [squeeze(norm_weights); 1; 1];
            full_weights = permute(full_weights, [2, 3, 4, 1]);
            
            weighted_image = gamma_image .* full_weights;
            
            
            %% flip_image
            if app.XButton.Value
                weighted_image = flip(weighted_image, 2);
            end
            
            if app.YButton.Value
                weighted_image = flip(weighted_image, 1);
            end
            
            if app.flipZ
                weighted_image = flip(weighted_image, 3);
            end
            
            %% rotate image
            rotated_stack = imrotate(weighted_image, -app.Knob.Value, 'bilinear');
            
            
            mat_to_save = app.full_file;
            mat_to_save.gamma = gamma;
            mat_to_save.mask = app.mask;
            mat_to_save.weights = full_weights(:);
            mat_to_save.flipX = app.XButton.Value;
            mat_to_save.flipY = app.YButton.Value;
            mat_to_save.flipZ = app.flipZ;
            mat_to_save.rotation = app.Knob.Value;
            
            mat_to_save.data = rotated_stack;
            save(app.path, '-struct', 'mat_to_save');
            
            % remove the old neuropal file so that neuropal doesn't load it
            [old_file_folder, old_file_name] = fileparts(app.path);
            old_file_path = fullfile(old_file_folder, [old_file_name '.mat']);
            delete(old_file_path);
            
            disp('saved!');
        end
        
        function select_head(app)
            disp_image = app.original_image;
            max_val = app.max_of_channel(disp_image);
            disp_image = disp_image ./ max_val;
            disp_image(disp_image > 1) = 1;
            
            h = figure;
            imagesc(disp_image);
            axis('equal');
            roi = drawpolygon;
            app.mask = roi.createMask;
            close(h);
            
            app.plot_zstack();
        end
        
        function reset_sliders(app)
            % grab weights
            app.RedSlider.Value = app.full_file.weights(5);
            app.GreenSlider.Value = app.full_file.weights(3);
            app.BlueSlider.Value = app.full_file.weights(1);
            
            % grab gamma
            app.GammaRedSlider.Value = app.full_file.gamma(5);
            app.GammaGreenSlider.Value = app.full_file.gamma(3);
            app.GammaBlueSlider.Value = app.full_file.gamma(1);
            
            % grap flip
            app.XButton.Value = app.full_file.flipX;
            app.YButton.Value = app.full_file.flipY;
            app.flipZ = app.full_file.flipZ;
            
            % grab rotation
            app.Knob.Value = app.full_file.rotation;
            
            app.plot_zstack();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.load_image();
        end

        % Button pushed function: SelectHeadButton
        function SelectHeadButtonPushed(app, event)
            app.select_head();
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            app.reset_sliders();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'PLEASE WAIT';
            pause(0.1);
            app.save_image();
            app.SaveButton.Text = 'Save';
        end

        % Value changed function: GammaRedSlider
        function GammaRedSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GammaGreenSlider
        function GammaGreenSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: GammaBlueSlider
        function GammaBlueSliderValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.plot_zstack();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 1];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.Value = 0.5;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 1];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.Value = 0.5;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 1];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.Value = 0.5;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectHeadButton = uibutton(app.UIFigure, 'push');
            app.SelectHeadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadButtonPushed, true);
            app.SelectHeadButton.Text = 'Select Head';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.ResetSlidersButton = uibutton(app.UIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GammaGreenSlider = uislider(app.UIFigure);
            app.GammaGreenSlider.Limits = [0 2];
            app.GammaGreenSlider.ValueChangedFcn = createCallbackFcn(app, @GammaGreenSliderValueChanged, true);
            app.GammaGreenSlider.Value = 0.8;
            app.GammaBlueSlider = uislider(app.UIFigure);
            app.GammaBlueSlider.Limits = [0 2];
            app.GammaBlueSlider.ValueChangedFcn = createCallbackFcn(app, @GammaBlueSliderValueChanged, true);
            app.GammaBlueSlider.Value = 0.5;
            app.GammaRedSlider = uislider(app.UIFigure);
            app.GammaRedSlider.Limits = [0 2];
            app.GammaRedSlider.ValueChangedFcn = createCallbackFcn(app, @GammaRedSliderValueChanged, true);
            app.GammaRedSlider.Value = 0.5;
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = '';
            app.ScaleLabel = uilabel(app.UIFigure);
            app.ScaleLabel.Text = 'Scale';
            app.GammaLabel = uilabel(app.UIFigure);
            app.GammaLabel.Text = 'Gamma';
            app.NoteXYflipsactlikephysicalflipsTheywillfliptheZaxisaswellLabel = uilabel(app.UIFigure);
            app.NoteXYflipsactlikephysicalflipsTheywillfliptheZaxisaswellLabel.Text = {'Note: X,Y flips act like physical flips'; 'They will flip the Z axis as well'; 'As long as the worm is facing left and '; 'ventral side is down, leftside is top and '; 'rightside is bottom'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjust_multicolor_image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end